classdef GarbageInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Gauge4        matlab.ui.control.LinearGauge
        Gauge_4Label  matlab.ui.control.Label
        Gauge3        matlab.ui.control.LinearGauge
        Gauge_3Label  matlab.ui.control.Label
        Gauge2        matlab.ui.control.LinearGauge
        Gauge_2Label  matlab.ui.control.Label
        Gauge1        matlab.ui.control.LinearGauge
        GaugeLabel    matlab.ui.control.Label
        UITable       matlab.ui.control.Table
        UIAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        UpdateTimer
    end

    methods (Access = public)
        function runStartup(app)
            StartupFcn(app);
        end
    end
    
    methods (Access = private)
        
        function StartupFcn(app)
            app.UpdateTimer = timer( ...
                'ExecutionMode','fixedRate', ...
                'Period', 1.0, ...
                'TimerFcn', @(~,~)updateDashboard(app));
            start(app.UpdateTimer);
        end

        
        function UIFigureCloseRequest(app, event)
            try
                stop(app.UpdateTimer);
                delete(app.UpdateTimer);
            end
            delete(app);
        end

        
        function updateDashboard(app)
            if ~isfile("appData.mat")
                return;
            end
        
            S = load("appData.mat", "appData");
            if ~isfield(S, "appData"), return; end
            
            bins = S.appData.bins;
        
            % Update table
            app.UITable.Data = bins;
        
            % Bar chart
            levels = bins(:,2)';
            bar(app.UIAxes, levels);
            ylim(app.UIAxes, [0 100]);
            xticks(app.UIAxes, 1:4);
            title(app.UIAxes, "Garbage Level per Bin");
        
            % Update progress bars
            app.Gauge1.Value = levels(1);
            app.Gauge2.Value = levels(2);
            app.Gauge3.Value = levels(3);
            app.Gauge4.Value = levels(4);
        end

    end
    

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge1 = uigauge(app.UIFigure, 'linear');
            app.Gauge_2Label = uilabel(app.UIFigure);
            app.Gauge_2Label.Text = 'Gauge';
            app.Gauge2 = uigauge(app.UIFigure, 'linear');
            app.Gauge_3Label = uilabel(app.UIFigure);
            app.Gauge_3Label.Text = 'Gauge';
            app.Gauge3 = uigauge(app.UIFigure, 'linear');
            app.Gauge_4Label = uilabel(app.UIFigure);
            app.Gauge_4Label.Text = 'Gauge';
            app.Gauge4 = uigauge(app.UIFigure, 'linear');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GarbageInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end